classdef AFDeter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AFDeterTab                     matlab.ui.container.Tab
        RunLabel                       matlab.ui.control.Label
        AveragefiberdiametermLabel_3   matlab.ui.control.Label
        AirflowresistivityPacdotsm2Label_2  matlab.ui.control.Label
        Permeabilitycoefficient1010m2Label_4  matlab.ui.control.Label
        DLabel                         matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        BLabel_2                       matlab.ui.control.Label
        ALabel_2                       matlab.ui.control.Label
        EditField_E_R                  matlab.ui.control.NumericEditField
        EditField_E_K                  matlab.ui.control.NumericEditField
        EditField_K_R                  matlab.ui.control.NumericEditField
        EditField_K_K                  matlab.ui.control.NumericEditField
        AirflowresistivityPacdotsm2Label  matlab.ui.control.Label
        Permeabilitycoefficient1010m2Label_3  matlab.ui.control.Label
        AirflowrelatedPropertiesLabel  matlab.ui.control.Label
        Label_29                       matlab.ui.control.Label
        Label_28                       matlab.ui.control.Label
        Label_27                       matlab.ui.control.Label
        Label_26                       matlab.ui.control.Label
        Label_25                       matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        Label_24                       matlab.ui.control.Label
        Label_23                       matlab.ui.control.Label
        Label_22                       matlab.ui.control.Label
        BriefTutorialLabel             matlab.ui.control.Label
        CharacterizedParametersLabel   matlab.ui.control.Label
        ThicknessmmLabel_2             matlab.ui.control.Label
        PorosityLabel_2                matlab.ui.control.Label
        AveragefiberdiametermLabel_2   matlab.ui.control.Label
        ThicknessmmLabel_1             matlab.ui.control.Label
        PorosityLabel_1                matlab.ui.control.Label
        EditField_K_h                  matlab.ui.control.NumericEditField
        EditField_K_phi                matlab.ui.control.NumericEditField
        EditField_K_d                  matlab.ui.control.NumericEditField
        EditField_E_h                  matlab.ui.control.NumericEditField
        EditField_E_phi                matlab.ui.control.NumericEditField
        EditField_E_d                  matlab.ui.control.NumericEditField
        Label_21                       matlab.ui.control.Label
        EditField_E_delta              matlab.ui.control.NumericEditField
        Label_20                       matlab.ui.control.Label
        EditField_K_delta              matlab.ui.control.NumericEditField
        Lamp_E                         matlab.ui.control.Lamp
        Lamp_K                         matlab.ui.control.Lamp
        Lamp_E_eq                      matlab.ui.control.Lamp
        Lamp_K_eq                      matlab.ui.control.Lamp
        InversedEquationsLabel         matlab.ui.control.Label
        Button                         matlab.ui.control.StateButton
        CoefficientsandconstantLabel   matlab.ui.control.Label
        LowerBoundsLabel               matlab.ui.control.Label
        UpperBoundsLabel               matlab.ui.control.Label
        PorosityLabel                  matlab.ui.control.Label
        ThicknessmmLabel               matlab.ui.control.Label
        AveragefiberdiametermLabel     matlab.ui.control.Label
        Sh_low                         matlab.ui.control.Spinner
        Sh_high                        matlab.ui.control.Spinner
        Srho_low                       matlab.ui.control.Spinner
        Srho_high                      matlab.ui.control.Spinner
        Sd_low                         matlab.ui.control.Spinner
        Sd_high                        matlab.ui.control.Spinner
        SA_low                         matlab.ui.control.Spinner
        SA_high                        matlab.ui.control.Spinner
        SB_low                         matlab.ui.control.Spinner
        SB_high                        matlab.ui.control.Spinner
        ALabel                         matlab.ui.control.Label
        BLabel                         matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ImportData                     matlab.ui.control.Button
        ImportDataLabel                matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField_3                    matlab.ui.control.EditField
        EditField_4                    matlab.ui.control.EditField
        UIAxes_E_eq                    matlab.ui.control.UIAxes
        UIAxes_K_eq                    matlab.ui.control.UIAxes
        UIAxes_imported                matlab.ui.control.UIAxes
        UIAxes_Kozeny                  matlab.ui.control.UIAxes
        UIAxes_Ergun                   matlab.ui.control.UIAxes
        InstructionTab                 matlab.ui.container.Tab
        Image5                         matlab.ui.control.Image
        ThissoftwareisusedtoLabel_7    matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        ThissoftwareisusedtoLabel_6    matlab.ui.control.Label
        ThissoftwareisusedtoLabel_5    matlab.ui.control.Label
        ThissoftwareisusedtoLabel_4    matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ThissoftwareisusedtoLabel_3    matlab.ui.control.Label
        StepsLabel                     matlab.ui.control.Label
        ImportanttipsLabel             matlab.ui.control.Label
        GeneralinformationLabel        matlab.ui.control.Label
        ThissoftwareisusedtoLabel_2    matlab.ui.control.Label
        ThissoftwareisusedtoLabel      matlab.ui.control.Label
        HelpTab                        matlab.ui.container.Tab
        ContactTextArea_2              matlab.ui.control.TextArea
        ThissoftwareisusedtoLabel_11   matlab.ui.control.Label
        AcknowledgementsLabel          matlab.ui.control.Label
        ThissoftwareisusedtoLabel_10   matlab.ui.control.Label
        BugreportLabel                 matlab.ui.control.Label
        NeedhelpLabel                  matlab.ui.control.Label
        ThissoftwareisusedtoLabel_9    matlab.ui.control.Label
        ContactTextArea                matlab.ui.control.TextArea
        ContactLabel                   matlab.ui.control.Label
        ThissoftwareisusedtoLabel_8    matlab.ui.control.Label
    end

    
    properties (Access = public)
        measured_u % measured airflow velocity
        measured_p % measured presssure drop
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportData
        function ImportDataButtonPushed(app, event)
            % clear the result from previous calculation
            cla(app.UIAxes_Ergun);
            cla(app.UIAxes_Kozeny);
            cla(app.UIAxes_imported);
            app.UITable.Data(:,:)=[];
            app.EditField_E_delta.Value=0;
            app.EditField_K_delta.Value=0;
            app.EditField_K_h.Value=0;
            app.EditField_K_phi.Value=0;
            app.EditField_K_d.Value=0;
            app.EditField_E_h.Value=0;
            app.EditField_E_phi.Value=0;
            app.EditField_E_d.Value=0;
            app.EditField_K_K.Value=0;
            app.EditField_K_R.Value=0;
            app.EditField_E_K.Value=0;
            app.EditField_E_R.Value=0;
            app.Sh_low.Value=10;
            app.Sh_high.Value=10;
            app.Srho_low.Value=0.9;
            app.Srho_high.Value=0.9;
            app.Sd_low.Value=10;
            app.Sd_high.Value=10;
            app.SA_low.Value=150;
            app.SA_high.Value=250;
            app.SB_low.Value=0.1;
            app.SB_high.Value=2;
            cla(app.UIAxes_K_eq);
            cla(app.UIAxes_E_eq);
            app.Lamp_K.Color='[0.90,0.90,0.90]';
            app.Lamp_K_eq.Color='[0.90,0.90,0.90]';
            app.Lamp_E.Color='[0.90,0.90,0.90]';
            app.Lamp_E_eq.Color='[0.90,0.90,0.90]';
          
            % load the data
            [file,path,~] = uigetfile({'*.xlsx'},'Select the Excel file');
            filename = fullfile(path, file);
            measured_results = importdata(filename);
            app.measured_u=measured_results(:,1);
            app.measured_p=measured_results(:,2);
            app.UITable.Data = measured_results;
            scatter(app.UIAxes_imported,app.measured_u,app.measured_p,...
                "magenta","filled"); 
            legend(app.UIAxes_imported,"Measured value","fontsize",12,...
                "AutoUpdate","off","Location","northwest");
            legend(app.UIAxes_imported,"boxoff");
            
            if any(isnan(app.measured_u),'all')||...
                    any(isnan(app.measured_p),'all')||...
                    any(app.measured_u<=0)||...
                    any(app.measured_p<=0)
                fig = uifigure('Position',[300 300 425 160]);
                message = sprintf('Check the import data! \n The airflow velocity and pressure drop should have the same size, and they should be valid.');
                uialert(fig,message,'Warning','Icon','warning',"CloseFcn",@(h,e) close(fig));
                close(app.UIAxes_imported); 
                app.UITable.Data(:,:)=[];
            end
            if size(app.measured_p,1)<20||size(app.measured_u,1)<20
                fig = uifigure('Position',[300 300 425 160]);
                message = sprintf('More data is recommended! \n More data result in higher accuracy, at least 20 sets of data.');
                uialert(fig,message,'Infomation','Icon','info',"CloseFcn",@(h,e) close(fig));
                close(app.UIAxes_imported); 
                app.UITable.Data(:,:)=[];
            end

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            % warning for lower bound higher than upper bounds
            if app.SA_low.Value>app.SA_high.Value || app.Srho_low.Value>app.Srho_low.Value ||...
                    app.Sh_low.Value>app.Sh_high.Value || app.Sd_low.Value>app.Sd_low.Value || app.SB_low.Value>app.SB_high.Value
                fig = uifigure('Position',[150 150 425 180]);
                message = sprintf('Parameter setting error! \n The lower bounds should smaller than the upper bounds.');
                uialert(fig,message,'Warning','Icon','warning',"CloseFcn",@(h,e) close(fig));
                close(app.UIAxes_Kozeny);
            end
            if any(isnan(app.measured_u),'all')||...
                    any(isnan(app.measured_p),'all')||...
                    any(app.measured_u<=0)||...
                    any(app.measured_p<=0)
                fig = uifigure('Position',[300 300 380 160]);
                message = sprintf('Check the import data! \n The data is not valid.');
                uialert(fig,message,'Warning','Icon','warning',"CloseFcn",@(h,e) close(fig));
                close(app.UIAxes_imported);
                app.UITable.Data(:,:)=[];
            end

            if any(isempty(app.measured_u),"all")||...
                    any(isempty(app.measured_p),'all')
                fig = uifigure('Position',[150 150 425 180]);
                message = sprintf('Import data first! \n There is no data for parameter charaterization.');
                uialert(fig,message,'Infomation','Icon','info',"CloseFcn",@(h,e) close(fig));
            cla(app.UIAxes_Kozeny);
            cla(app.UIAxes_Ergun);
            delete(findobj(app.UIAxes_K_eq,'type','text'));
            delete(findobj(app.UIAxes_E_eq,'type','text'));
            app.EditField_E_delta.Value=0;
            app.EditField_K_delta.Value=0;
            app.EditField_K_h.Value=0;
            app.EditField_K_phi.Value=0;
            app.EditField_K_d.Value=0;
            app.EditField_E_h.Value=0;
            app.EditField_E_phi.Value=0;
            app.EditField_E_d.Value=0;
            app.EditField_K_K.Value=0;
            app.EditField_K_R.Value=0;
            app.EditField_E_K.Value=0;
            app.EditField_E_R.Value=0;
            app.Sh_low.Value=10;
            app.Sh_high.Value=10;
            app.Srho_low.Value=0.9;
            app.Srho_high.Value=0.9;
            app.Sd_low.Value=10;
            app.Sd_high.Value=10;
            app.SA_low.Value=150;
            app.SA_high.Value=250;
            app.SB_low.Value=0.1;
            app.SB_high.Value=2;
            app.Lamp_K.Color='[0.90,0.90,0.90]';
            app.Lamp_K_eq.Color='[0.90,0.90,0.90]';
            app.Lamp_E.Color='[0.90,0.90,0.90]';
            app.Lamp_E_eq.Color='[0.90,0.90,0.90]';
            end

            % Kozeny-type equation
            fun=@(y) sum((app.measured_p-y(1)*(1-y(2)).^2*1.846*10^-5*y(3)*10^-3*app.measured_u*10^-3....
            /(y(2).^3*(1.5*y(4)*10^-6).^2)).^2)/size(app.measured_p,1); 

            y0=[app.SA_low.Value app.Srho_low.Value app.Sh_low.Value app.Sd_low.Value];
            y=fminsearchbnd(fun,y0,[app.SA_low.Value app.Srho_low.Value app.Sh_low.Value app.Sd_low.Value],...
                [app.SA_high.Value app.Srho_high.Value app.Sh_high.Value app.Sd_high.Value]);
            p_K=y(1)*(1-y(2)).^2*1.846*10^-5*y(3)*10^-3*app.measured_u*10^-3./(y(2).^3*(1.5*y(4)*10^-6).^2);

            plot(app.UIAxes_Kozeny,app.measured_u,p_K,"b","LineWidth",2);
            hold (app.UIAxes_Kozeny);
            scatter(app.UIAxes_Kozeny,app.measured_u,app.measured_p,"magenta","filled");
             legend(app.UIAxes_Kozeny,"The value based on"+ newline +"Kozeny-type equation","fontsize",12,"AutoUpdate","off","Location","northwest");
            legend(app.UIAxes_Kozeny,"boxoff");
            hold(app.UIAxes_Kozeny,"off");  
            
            % Ergun-type equation
            fun=@(x) sum((app.measured_p-(x(1).*(1-x(2)).^2*1.846*10^-5.*x(3)*10^-3.*app.measured_u.*10^-3....
                /(x(2).^3*(1.5*x(4)*10^-6).^2)+x(5).*(1-x(2)).*1.204.*x(3)*10^-3.*(app.measured_u*10^-3).^2....
                /(x(2).^3.*1.5*x(4)*10^-6))).^2)/size(app.measured_p,1);
            x0=[app.SA_low.Value app.Srho_low.Value app.Sh_low.Value app.Sd_low.Value app.SB_low.Value];
            x=fminsearchbnd(fun,x0,[app.SA_low.Value app.Srho_low.Value app.Sh_low.Value app.Sd_low.Value app.SB_low.Value],...
                [app.SA_high.Value app.Srho_high.Value app.Sh_high.Value app.Sd_high.Value app.SB_high.Value]);
            p_E=x(1).*(1-x(2)).^2*1.846*10^-5.*x(3).*10^-3.*app.measured_u.*10^-3....
                /(x(2).^3.*(1.5*x(4)*10^-6).^2)+x(5).*(1-x(2)).*1.204.*x(3).*10^-3....
                *(app.measured_u*10^-3).^2./(x(2).^3.*1.5*x(4)*10^-6);
   
            plot(app.UIAxes_Ergun,app.measured_u,p_E,"b","LineWidth",2);
            hold (app.UIAxes_Ergun);
            scatter(app.UIAxes_Ergun,app.measured_u,app.measured_p,"magenta","filled");
            legend(app.UIAxes_Ergun,"The value based on"+ newline +"Ergun-type equation","fontsize",12,"AutoUpdate","off","Location","northwest");
            legend(app.UIAxes_Ergun,"boxoff");
            hold(app.UIAxes_Ergun,"off"); 
            
            % display equations
            % Kozeny-type equation
            text(app.UIAxes_K_eq,0.01,0.5,sprintf('$$ \\Delta P=%.2f{\\frac {(1- \\phi )^2} {\\phi ^3}}{\\frac {\\eta hu} {(1.5d)^2}}$$',y(1)),'Interpreter','latex','FontSize',13);
            % Ergun-type equation
            text(app.UIAxes_E_eq,0.01,0.5,sprintf('$$ \\Delta P=%.2f{\\frac {(1- \\phi )^2} {\\phi ^3}}{\\frac {\\eta hu} {(1.5d)^2}} + %.2f{\\frac {(1-\\phi)}{\\phi^3}}{\\frac{\\rho_{air} hu^2} {1.5d}}$$',x(1),x(5)),'Interpreter','latex','FontSize',13);
            
            % display parameters
            app.EditField_K_h.Value=y(3);
            app.EditField_K_phi.Value=y(2);
            app.EditField_K_d.Value=y(4);
            app.EditField_E_h.Value=x(3);
            app.EditField_E_phi.Value=x(2);
            app.EditField_E_d.Value=x(4);
            
            % relative difference between measured and pridicted pressure
            % drop
            app.EditField_K_delta.Value=((sum(abs(p_K-app.measured_p)./...
                app.measured_p))/size(app.measured_p,1))*100;
            app.EditField_E_delta.Value=((sum(abs(p_E-app.measured_p)./...
                app.measured_p))/size(app.measured_p,1))*100;
            
            % recommodation identification
            if max(app.measured_u)<400
                app.Lamp_K.Color='g';
                app.Lamp_K_eq.Color='g';
                app.Lamp_E.Color='r';
                app.Lamp_E_eq.Color='r';
            elseif sum(abs(app.measured_p-p_K)) < sum(abs(app.measured_p-p_E))
                app.Lamp_K.Color='g';
                app.Lamp_K_eq.Color='g';
                app.Lamp_E.Color='r';
                app.Lamp_E_eq.Color='r';
            elseif sum(abs(app.measured_p-p_K)) == sum(abs(app.measured_p-p_E))
                app.Lamp_K.Color='g';
                app.Lamp_K_eq.Color='g';
                app.Lamp_E.Color='g';
                app.Lamp_E_eq.Color='g';
            else 
                app.Lamp_K.Color='r';
                app.Lamp_K_eq.Color='r';
                app.Lamp_E.Color='g';
                app.Lamp_E_eq.Color='g';
            end

            % display a warning if the relative difference more than 100 % 
            if app.EditField_K_delta.Value>100||...
                    app.EditField_E_delta.Value>100
                fig = uifigure('Position',[300 300 380 160]);
                message = sprintf('The results are not reliable! \n Please check the import data and the bounds setting.');
                uialert(fig,message,'Warning','Icon','warning',"CloseFcn",@(h,e) close(fig));
                close(app.UIAxes_imported); 
                app.UITable.Data(:,:)=[];
                cla(app.UIAxes_Kozeny);
                cla(app.UIAxes_Ergun);
%                 app.EditField_E_delta.Value=0;
%                 app.EditField_K_delta.Value=0;
                app.EditField_K_h.Value=0;
                app.EditField_K_phi.Value=0;
                app.EditField_K_d.Value=0;
                app.EditField_E_h.Value=0;
                app.EditField_E_phi.Value=0;
                app.EditField_E_d.Value=0;
                app.EditField_K_K.Value=0;
                app.EditField_K_R.Value=0;
                app.EditField_E_K.Value=0;
                app.EditField_E_R.Value=0;
                app.Sh_low.Value=10;
                app.Sh_high.Value=10;
                app.Srho_low.Value=0.9;
                app.Srho_high.Value=0.9;
                app.Sd_low.Value=10;
                app.Sd_high.Value=10;
                app.SA_low.Value=150;
                app.SA_high.Value=250;
                app.SB_low.Value=0.1;
                app.SB_high.Value=2;
                delete(findobj(app.UIAxes_K_eq,'type','text'));
                delete(findobj(app.UIAxes_E_eq,'type','text'));
                app.Lamp_K.Color='[0.90,0.90,0.90]';
                app.Lamp_K_eq.Color='[0.90,0.90,0.90]';
                app.Lamp_E.Color='[0.90,0.90,0.90]';
                app.Lamp_E_eq.Color='[0.90,0.90,0.90]';
            end

            % permeability coefficient and airflow velocity
            % Kozeny-type equation
            K_K=y(2)^3*(y(4)*10^-6)^2/(y(1)*(1-y(2))^2);
            K_R=y(1)*(1-y(2))^2*1.846*10^-5/((y(2)^3*(y(4)*10^-6)^2));
            app.EditField_K_K.Value=K_K*10^10;
            app.EditField_K_R.Value=K_R;

            % Ergun-type equation
            E_K=x(2)^3*(x(4)*10^-6)^2/(x(1)*(1-x(2))^2);
            E_R=x(1)*(1-x(2))^2*1.846*10^-5/((x(2)^3*(x(4)*10^-6)^2))+x(5)*(1-x(2))*1.204*0.5*10^-3/(x(4)*10^-6);
            app.EditField_E_K.Value=E_K*10^10;
            app.EditField_E_R.Value=E_R;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AFDeterTab = uitab(app.TabGroup);
            app.AFDeterTab.Title = 'AFDeter';
            app.UIAxes_Ergun = uiaxes(app.AFDeterTab);
            title(app.UIAxes_Ergun, 'Ergun-type equation')
            xlabel(app.UIAxes_Ergun, 'Airflow velocity (mm/s)')
            ylabel(app.UIAxes_Ergun, 'Pressure drop')
            zlabel(app.UIAxes_Ergun, 'Z')
            app.UIAxes_Kozeny = uiaxes(app.AFDeterTab);
            title(app.UIAxes_Kozeny, 'Kozeny-type equation')
            xlabel(app.UIAxes_Kozeny, 'Airflow velocity (mm/s)')
            ylabel(app.UIAxes_Kozeny, 'Pressure drop')
            zlabel(app.UIAxes_Kozeny, 'Z')
            app.UIAxes_imported = uiaxes(app.AFDeterTab);
            title(app.UIAxes_imported, 'Measured airflow velocity and pressure drop')
            xlabel(app.UIAxes_imported, 'Airflow velocity (mm/s)')
            ylabel(app.UIAxes_imported, 'Pressure drop (Pa)')
            zlabel(app.UIAxes_imported, 'Z')
            app.UIAxes_K_eq = uiaxes(app.AFDeterTab);
            zlabel(app.UIAxes_K_eq, 'Z')
            app.UIAxes_K_eq.XTick = [];
            app.UIAxes_K_eq.YTick = [];
            app.UIAxes_E_eq = uiaxes(app.AFDeterTab);
            zlabel(app.UIAxes_E_eq, 'Z')
            app.UIAxes_E_eq.XTick = [];
            app.UIAxes_E_eq.YTick = [];
            app.EditField_4 = uieditfield(app.AFDeterTab, 'text');
            app.EditField_3 = uieditfield(app.AFDeterTab, 'text');
            app.EditField_2 = uieditfield(app.AFDeterTab, 'text');
            app.EditField = uieditfield(app.AFDeterTab, 'text');
            app.ImportDataLabel = uilabel(app.AFDeterTab);
            app.ImportDataLabel.Text = 'Import Data';
            app.ImportData = uibutton(app.AFDeterTab, 'push');
            app.ImportData.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportData.Icon = 'Icon-Import data.png';
            app.ImportData.Text = '';
            app.UITable = uitable(app.AFDeterTab);
            app.UITable.ColumnName = {'Airflow velocity (mm/s)'; 'Pressure drop (Pa)'};
            app.UITable.RowName = {};
            app.BLabel = uilabel(app.AFDeterTab);
            app.BLabel.Interpreter = 'latex';
            app.BLabel.Text = 'B';
            app.ALabel = uilabel(app.AFDeterTab);
            app.ALabel.Interpreter = 'latex';
            app.ALabel.Text = 'A';
            app.SB_high = uispinner(app.AFDeterTab);
            app.SB_high.Step = 0.1;
            app.SB_high.Limits = [1 2];
            app.SB_high.Value = 2;
            app.SB_low = uispinner(app.AFDeterTab);
            app.SB_low.Step = 0.1;
            app.SB_low.Limits = [0.1 1];
            app.SB_low.Value = 0.1;
            app.SA_high = uispinner(app.AFDeterTab);
            app.SA_high.Limits = [150 250];
            app.SA_high.Value = 250;
            app.SA_low = uispinner(app.AFDeterTab);
            app.SA_low.Limits = [150 200];
            app.SA_low.Value = 150;
            app.Sd_high = uispinner(app.AFDeterTab);
            app.Sd_high.Limits = [0.1 100];
            app.Sd_high.Value = 10;
            app.Sd_low = uispinner(app.AFDeterTab);
            app.Sd_low.Step = 0.01;
            app.Sd_low.Limits = [0.01 100];
            app.Sd_low.Value = 10;
            app.Srho_high = uispinner(app.AFDeterTab);
            app.Srho_high.Step = 0.0001;
            app.Srho_high.Limits = [0.6 0.9999];
            app.Srho_high.Value = 0.9;
            app.Srho_low = uispinner(app.AFDeterTab);
            app.Srho_low.Step = 0.01;
            app.Srho_low.Limits = [0.5 0.99];
            app.Srho_low.Value = 0.9;
            app.Sh_high = uispinner(app.AFDeterTab);
            app.Sh_high.Limits = [0 100];
            app.Sh_high.Value = 10;
            app.Sh_low = uispinner(app.AFDeterTab);
            app.Sh_low.Step = 0.01;
            app.Sh_low.Limits = [0 20];
            app.Sh_low.Value = 10;
            app.AveragefiberdiametermLabel = uilabel(app.AFDeterTab);
            app.AveragefiberdiametermLabel.Interpreter = 'latex';
            app.AveragefiberdiametermLabel.Text = 'Average fiber diameter (µm)';
            app.ThicknessmmLabel = uilabel(app.AFDeterTab);
            app.ThicknessmmLabel.Interpreter = 'latex';
            app.ThicknessmmLabel.Text = 'Thickness (mm)';
            app.PorosityLabel = uilabel(app.AFDeterTab);
            app.PorosityLabel.Interpreter = 'latex';
            app.PorosityLabel.Text = 'Porosity';
            app.UpperBoundsLabel = uilabel(app.AFDeterTab);
            app.UpperBoundsLabel.Text = 'Upper Bounds';
            app.LowerBoundsLabel = uilabel(app.AFDeterTab);
            app.LowerBoundsLabel.Text = 'Lower Bounds';
            app.CoefficientsandconstantLabel = uilabel(app.AFDeterTab);
            app.CoefficientsandconstantLabel.Text = 'Coefficients and constant';
            app.Button = uibutton(app.AFDeterTab, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'Icon-START.png';
            app.Button.Text = '';
            app.InversedEquationsLabel = uilabel(app.AFDeterTab);
            app.InversedEquationsLabel.Text = 'Inversed Equations';
            app.Lamp_K_eq = uilamp(app.AFDeterTab);
            app.Lamp_E_eq = uilamp(app.AFDeterTab);
            app.Lamp_K = uilamp(app.AFDeterTab);
            app.Lamp_E = uilamp(app.AFDeterTab);
            app.EditField_K_delta = uieditfield(app.AFDeterTab, 'numeric');
            app.Label_20 = uilabel(app.AFDeterTab);
            app.Label_20.Text = '∆(%):';
            app.EditField_E_delta = uieditfield(app.AFDeterTab, 'numeric');
            app.Label_21 = uilabel(app.AFDeterTab);
            app.Label_21.Text = '∆(%):';
            app.EditField_E_d = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_E_d.Editable = 'off';
            app.EditField_E_phi = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_E_phi.Editable = 'off';
            app.EditField_E_h = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_E_h.Editable = 'off';
            app.EditField_K_d = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_K_d.Editable = 'off';
            app.EditField_K_phi = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_K_phi.Editable = 'off';
            app.EditField_K_h = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_K_h.Editable = 'off';
            app.PorosityLabel_1 = uilabel(app.AFDeterTab);
            app.PorosityLabel_1.Interpreter = 'latex';
            app.PorosityLabel_1.Text = 'Porosity';
            app.ThicknessmmLabel_1 = uilabel(app.AFDeterTab);
            app.ThicknessmmLabel_1.Interpreter = 'latex';
            app.ThicknessmmLabel_1.Text = 'Thickness (mm)';
            app.AveragefiberdiametermLabel_2 = uilabel(app.AFDeterTab);
            app.AveragefiberdiametermLabel_2.Interpreter = 'latex';
            app.AveragefiberdiametermLabel_2.Text = 'Average fiber diameter (µm)';
            app.PorosityLabel_2 = uilabel(app.AFDeterTab);
            app.PorosityLabel_2.Interpreter = 'latex';
            app.PorosityLabel_2.Text = 'Porosity';
            app.ThicknessmmLabel_2 = uilabel(app.AFDeterTab);
            app.ThicknessmmLabel_2.Interpreter = 'latex';
            app.ThicknessmmLabel_2.Text = 'Thickness (mm)';
            app.CharacterizedParametersLabel = uilabel(app.AFDeterTab);
            app.CharacterizedParametersLabel.Text = 'Characterized Parameters';
            app.BriefTutorialLabel = uilabel(app.AFDeterTab);
            app.BriefTutorialLabel.Text = 'Brief Tutorial';
            app.Label_22 = uilabel(app.AFDeterTab);
            app.Label_22.Text = 'Click the ''Import Data'' icon to read the data of Airflow velocity-Pressure drop.';
            app.Label_23 = uilabel(app.AFDeterTab);
            app.Label_23.Text = 'The data will be dispalyed in the table and graph.';
            app.Label_24 = uilabel(app.AFDeterTab);
            app.Label_24.Text = 'Imput or click the spinners to set the lower and upper bounds.';
            app.Label_14 = uilabel(app.AFDeterTab);
            app.Label_14.Text = 'It is suggested to keep the ''Coefficients and constant'' as the default.';
            app.Label_25 = uilabel(app.AFDeterTab);
            app.Label_25.Text = 'Click the ''START'' icon to fit the Kozeny-type and Ergun-type equations with the measured Airflow velocity-Pressure drop.';
            app.Label_26 = uilabel(app.AFDeterTab);
            app.Label_26.Text = 'The data and the relative error will be dispalyed and compared in the graphs.';
            app.Label_27 = uilabel(app.AFDeterTab);
            app.Label_27.Text = 'The lamps indicate which equation is suitable (i.e., green means more suitable and red means less suitble).';
            app.Label_28 = uilabel(app.AFDeterTab);
            app.Label_28.Text = 'The inversed Kozeny-type and Ergun-type equations will be displayed in the text areas.';
            app.Label_29 = uilabel(app.AFDeterTab);
            app.Label_29.Text = 'The characterized parameter and two airflow-related properties (permeability and airflow resistivity) is given.';
            app.AirflowrelatedPropertiesLabel = uilabel(app.AFDeterTab);
            app.AirflowrelatedPropertiesLabel.Text = 'Airflow-related Properties';
            app.Permeabilitycoefficient1010m2Label_3 = uilabel(app.AFDeterTab);
            app.Permeabilitycoefficient1010m2Label_3.Interpreter = 'latex';
            app.Permeabilitycoefficient1010m2Label_3.Text = {'Permeability coefficient      '; '        ($10^{-10}  m^2$)'};
            app.AirflowresistivityPacdotsm2Label = uilabel(app.AFDeterTab);
            app.AirflowresistivityPacdotsm2Label.Interpreter = 'latex';
            app.AirflowresistivityPacdotsm2Label.Text = 'Airflow resistivity ($Pa \cdot s/m^2$)';
            app.EditField_K_K = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_K_K.Editable = 'off';
            app.EditField_K_R = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_K_R.Editable = 'off';
            app.EditField_E_K = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_E_K.Editable = 'off';
            app.EditField_E_R = uieditfield(app.AFDeterTab, 'numeric');
            app.EditField_E_R.Editable = 'off';
            app.ALabel_2 = uilabel(app.AFDeterTab);
            app.ALabel_2.Text = 'A:';
            app.BLabel_2 = uilabel(app.AFDeterTab);
            app.BLabel_2.Text = 'B:';
            app.CLabel = uilabel(app.AFDeterTab);
            app.CLabel.Text = 'C:';
            app.DLabel = uilabel(app.AFDeterTab);
            app.DLabel.Text = 'D:';
            app.Permeabilitycoefficient1010m2Label_4 = uilabel(app.AFDeterTab);
            app.Permeabilitycoefficient1010m2Label_4.Interpreter = 'latex';
            app.Permeabilitycoefficient1010m2Label_4.Text = {'Permeability coefficient      '; '        ($10^{-10}  m^2$)'};
            app.AirflowresistivityPacdotsm2Label_2 = uilabel(app.AFDeterTab);
            app.AirflowresistivityPacdotsm2Label_2.Interpreter = 'latex';
            app.AirflowresistivityPacdotsm2Label_2.Text = 'Airflow resistivity ($Pa \cdot s/m^2$)';
            app.AveragefiberdiametermLabel_3 = uilabel(app.AFDeterTab);
            app.AveragefiberdiametermLabel_3.Interpreter = 'latex';
            app.AveragefiberdiametermLabel_3.Text = 'Average fiber diameter (µm)';
            app.RunLabel = uilabel(app.AFDeterTab);
            app.RunLabel.Text = 'Run';
            app.InstructionTab = uitab(app.TabGroup);
            app.InstructionTab.Title = 'Instruction';
            app.ThissoftwareisusedtoLabel = uilabel(app.InstructionTab);
            app.ThissoftwareisusedtoLabel.Text = {'AFDeter, a Matlab-based app or a standalone app, is used to simultaneously determine the structural parameters (i.e., porosity, thickness and average fiber diameter) of fibrous materials based on their airflow penetration behavior.        '; 'It can be applied on a variety of fibrous materials, such as needle punched, melt blown, spunlacea conventional nonwoven fabrics, electrospinning nanofiber webs and so on.                                                       '};
            app.ThissoftwareisusedtoLabel_2 = uilabel(app.InstructionTab);
            app.ThissoftwareisusedtoLabel_2.Text = '1. The measued airlfow velocity and pressure drop are recommended in a relatively wide range and more than 20 sets. Their accuracy need to be ensured, e.g., the CV < 3 %.                                                                                                                                                                                 2. The fibrous samples should stay in a natural condition (not be compressed) during the determination of airflow penetration behavior, or the thickness change is negligible.  ';
            app.GeneralinformationLabel = uilabel(app.InstructionTab);
            app.GeneralinformationLabel.Text = 'General information';
            app.ImportanttipsLabel = uilabel(app.InstructionTab);
            app.ImportanttipsLabel.Text = 'Important tips ';
            app.StepsLabel = uilabel(app.InstructionTab);
            app.StepsLabel.Text = 'Steps';
            app.ThissoftwareisusedtoLabel_3 = uilabel(app.InstructionTab);
            app.ThissoftwareisusedtoLabel_3.Text = '1. Click ''Import Data'' icon to import the airflow velocity-pressure drop data from an excel document. The first column defaults as airflow velocity (mm/s), and the second column for pressure drop (Pa).';
            app.Image = uiimage(app.InstructionTab);
            app.Image.ImageSource = 'Icon-Import data.png';
            app.Image2 = uiimage(app.InstructionTab);
            app.Image2.ImageSource = 'InverPara_Bounds.jpg';
            app.ThissoftwareisusedtoLabel_4 = uilabel(app.InstructionTab);
            app.ThissoftwareisusedtoLabel_4.Text = '2. Imput or click the spinners to set the lower and upper bounds.';
            app.ThissoftwareisusedtoLabel_5 = uilabel(app.InstructionTab);
            app.ThissoftwareisusedtoLabel_5.Text = 'The ''Coefficients and constant'' are suggested to keep as the default. The bounds of thickness and fiber diameter of nano fibrous webs and membranes are recommended 0.01-2 and 0.01-0.4 (or smaller range), respectively.  ';
            app.ThissoftwareisusedtoLabel_6 = uilabel(app.InstructionTab);
            app.ThissoftwareisusedtoLabel_6.Text = '3. Click the ‘START'' icon to run the algorithm.';
            app.Image3 = uiimage(app.InstructionTab);
            app.Image3.ImageSource = 'Icon-START.png';
            app.Image4 = uiimage(app.InstructionTab);
            app.Image4.ImageSource = 'InverPara_Results.jpg';
            app.ThissoftwareisusedtoLabel_7 = uilabel(app.InstructionTab);
            app.ThissoftwareisusedtoLabel_7.Text = 'The green lamp represents recommendation of the equation, while the red lamp means not recommended. They are the same for the ''Characterized Parameters'' and ''Airflow-related properties''.';
            app.Image5 = uiimage(app.InstructionTab);
            app.Image5.ImageSource = 'InverPara_Results1.jpg';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.ThissoftwareisusedtoLabel_8 = uilabel(app.HelpTab);
            app.ThissoftwareisusedtoLabel_8.Text = 'This app, AFDeter, is based on the below articles:                                               ';
            app.ContactLabel = uilabel(app.HelpTab);
            app.ContactLabel.Text = 'Contact:';
            app.ContactTextArea = uitextarea(app.HelpTab);
            app.ContactTextArea.Editable = 'off';
            app.ContactTextArea.Value = {'Tao Yang, Ph.D.'; 'email: tao.yang@tul.cz, tao.yang123@foxmail.com'; ''; ''};
            app.ThissoftwareisusedtoLabel_9 = uilabel(app.HelpTab);
            app.ThissoftwareisusedtoLabel_9.Text = {'If you need help on this app operation, please contact the developer without any heisitation.   '; 'You are also welcome to discuss the knowledge, methods and technologies related to this app with us.'};
            app.NeedhelpLabel = uilabel(app.HelpTab);
            app.NeedhelpLabel.Text = 'Need help?';
            app.BugreportLabel = uilabel(app.HelpTab);
            app.BugreportLabel.Text = 'Bug report';
            app.ThissoftwareisusedtoLabel_10 = uilabel(app.HelpTab);
            app.ThissoftwareisusedtoLabel_10.Text = 'Please contact the developer for any bugs related to this app.';
            app.AcknowledgementsLabel = uilabel(app.HelpTab);
            app.AcknowledgementsLabel.Text = 'Acknowledgements';
            app.ThissoftwareisusedtoLabel_11 = uilabel(app.HelpTab);
            app.ThissoftwareisusedtoLabel_11.Text = 'Tao Yang would like to thank Xiaomeng Wang, Mengru Li, Yuanfeng Wang, Kai Yang, Xiaodong Tan, Deyou Yu and Sundaramoorthy Palanisamy for their suggestions and help throughout the testing of AFDeter.';
            app.ContactTextArea_2 = uitextarea(app.HelpTab);
            app.ContactTextArea_2.Editable = 'off';
            app.ContactTextArea_2.Value = {'[1] Yang, T., Hu, L., Yu, D., Xiong, X., Chvojka, J., Venkataraman, M., Petrů, M., Tomková, B., Morikawa, H., & Militký, J. (2022). Simple determination of key structural parameters for fibrous materials enabled by Ergun-Type and Kozeny-type equations. Polymer Testing, 108(January), 107514. https://doi.org/10.1016/j.polymertesting.2022.107514'; '[2] Ergun, S. (1952). Fluid flow through packed columns. Chemical Enfineering Progress, 48(2), 89–94.'; '[3] du Plessis, J. P., & Woudberg, S. (2008). Pore-scale derivation of the Ergun equation to enhance its adaptability and generalization. Chemical Engineering Science, 63(9), 2576–2586. https://doi.org/10.1016/j.ces.2008.02.017'; '[4] Niven, R. K. (2002). Physical insight into the Ergun and Wen and Yu equations for fluid flow in packed and fluidised beds. Chemical Engineering Science, 57(3), 527–534. https://doi.org/10.1016/S0009-2509(01)00371-2'; '[5] Yang, T., Hu, L., Petrů, M., Wang, X., Xiong, X., Yu, D., Mishra, R., & Militký, J. (2021). Determination of the permeability coefficient and airflow resistivity of nonwoven materials. Textile Research Journal, 92(1–2), 126–142. https://doi.org/10.1177/00405175211029212'; '[6] Yang, T., Mishra, R., Horoshenkov, K. V., Hurrell, A., Saati, F., & Xiong, X. (2018). A study of some airflow resistivity models for multi-component polyester fiber assembly. Applied Acoustics, 139, 75–81. https://doi.org/10.1016/j.apacoust.2018.04.023'; '[7] Yang, T., Xiong, X., Mishra, R., Novák, J., Chaloupek, J., Sanetrnik, F., & Militký, J. (2016). Investigation on acoustic behavior and air permeability of struto nonwovens. Fibers and Polymers, 17(12), 2078–2084. https://doi.org/10.1007/s12221-016-6967-9'; '[8] Nelder, J. A., & Mead, R. (1965). A Simplex Method for Function Minimization. The Computer Journal, 7(4), 308–313. https://doi.org/10.1093/comjnl/7.4.308'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFDeter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end